classdef final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Tiempo            matlab.ui.control.NumericEditField
        AlturaLabel_2     matlab.ui.control.Label
        BorrarButton      matlab.ui.control.Button
        Radio             matlab.ui.control.NumericEditField
        RadioLabel        matlab.ui.control.Label
        Resistencia       matlab.ui.control.NumericEditField
        ResistenciaLabel  matlab.ui.control.Label
        Masa              matlab.ui.control.NumericEditField
        MasaLabel         matlab.ui.control.Label
        Altura            matlab.ui.control.NumericEditField
        AlturaLabel       matlab.ui.control.Label
        SimularButton     matlab.ui.control.Button
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
        
            % Leer valores de entrada de la aplicación

    altura = app.Altura.Value
    m = app.Masa.Value
    a = app.Radio.Value
    r = app.Resistencia.Value
    tf = app.Tiempo.Value

    % Resto del código original


% momento magnético asumido
U = 10000;


% permeabilidad magnética en el vacío
m0 = 4*pi*10^-7;


% valor de las separaciones
h = 0.001;

%modificar resistencia
r = r*10^-8;

% funcion velocidad (v) y posición (z)
vel = @(t,z,v) v;
pos = @(t,z,v) (-(m*9.81)-((9*((m0*U)^2)*(a^4))/(4*r))*((z^2)/((z^2+a^2)^5))*v)/m;


% Tiempo inicial
t0 = 0;

% posición (aproximada de acuerdo a la investigación de altura promedio de una "drop tower") y velocidad inicial 
z0 = altura; 
v0 = 0;

% vector de tiempo 
t = t0:h:tf;


% vectores de tiempo, posición y velocidad
T = zeros(1,length(t));
Z = zeros(1,length(t));
V = zeros(1,length(t));
FEM = zeros(1,length(t));


% posición y velocidad inicial 
Z(1) = z0;
V(1) = v0;


% Runge-Kutta 4to orden

for i = 1:(length(t)-1)
    
    k1 = vel(T(i), Z(i), V(i));  %ambas funciones velocidad y posicion son del mismo tamaño
    k1_2 = pos(T(i), Z(i), V(i));
    
    k2 = vel(T(i) + (1/2)*h, Z(i) + (1/2)*k1*h, V(i) + (1/2)*k1_2*h);
    k2_2 = pos(T(i) + (1/2)*h, Z(i) + (1/2)*k1*h, V(i) + (1/2)*k1_2*h);
    
    k3 = vel(T(i) + (1/2)*h, Z(i) + (1/2)*k2*h, V(i) + (1/2)*k2_2*h);
    k3_2 = pos(T(i) + (1/2)*h, Z(i) + (1/2)*k2*h, V(i) + (1/2)*k2_2*h);
    
    k4 = vel(T(i) + h, Z(i) + k3*h, V(i) + k3_2*h);
    k4_2 = pos(T(i) + h, Z(i) + k3*h, V(i) + k3_2*h);


    % cálculo de la posición, velocidad y tiempo en el siguiente paso
    Z(i+1) = Z(i) + (1/6) * (k1 + 2*k2 + 2*k3 + k4) * h;
    V(i+1) = V(i) + (1/6) * (k1_2 + 2*k2_2 + 2*k3_2 + k4_2) * h; 
    T(i+1) = T(i) + h;

    % cálculo de la fuerza electromotriz inducida (fem)
    FEM(i) = -(9*((m0*U)^2)*(a^4)/(4*r))*((Z(i)^2)/((Z(i)^2+a^2)^5))*V(i);
end


% posición
    plot(app.UIAxes, T, Z); % Utiliza app.UIAxes para graficar en la interfaz
    title(app.UIAxes, 'Posición vs tiempo');
    xlabel(app.UIAxes, 'Tiempo (segundos)');
    ylabel(app.UIAxes, 'Altura (metros)');
    drawnow; % Mostrar la gráfica inicial

 % velocidad
plot(app.UIAxes2, T, V); % Utiliza app.UIAxes para graficar en la interfaz
    title(app.UIAxes2, 'Velocidad vs tiempo');
    xlabel(app.UIAxes2, 'Tiempo (segundos)');
    ylabel(app.UIAxes2, 'Velocidad (metros)');
    drawnow; % Mostrar la gráfica inicial

% fuerza
plot(app.UIAxes3, T, FEM); % Utiliza app.UIAxes para graficar en la interfaz
    title(app.UIAxes3, 'Fuerza vs tiempo');
    xlabel(app.UIAxes3, 'Tiempo (segundos)');
    ylabel(app.UIAxes3, 'Fuerza (metros)');
    drawnow; % Mostrar la gráfica inicial

% animación
title(app.UIAxes4, 'Animación');
xlabel(app.UIAxes4, 'Eje x');
ylabel(app.UIAxes4, 'Altura (metros)');
xlim(app.UIAxes4, [-1.2*a 1.2*a])
ylim(app.UIAxes4, [0 1.1*altura])
xline(app.UIAxes4, -0.7*a)
xline(app.UIAxes4, 0.7*a)
drawnow; % Mostrar la gráfica inicial

circle = viscircles(app.UIAxes4, [0, Z(1)], a); % Dibuja el círculo inicial
pause(1);


for i = 1:round(tf):(length(Z)-1)
    % Actualizar posición del círculo en la animación
    delete(circle); % Eliminar el círculo anterior
    circle = viscircles(app.UIAxes4, [0, Z(i+1)], a); % Dibujar el nuevo círculo en la posición actualizada
    

    pause(tf/(length(Z))); % Pausa para que se pueda apreciar la animación
end


        end

        % Button pushed function: BorrarButton
        function BorrarButtonPushed(app, event)
            cla(app.UIAxes,'reset')
            cla(app.UIAxes2,'reset')
            cla(app.UIAxes3,'reset')
            cla(app.UIAxes4,'reset')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Magnetic fall')
            xlabel(app.UIAxes, 'Tiempo(segundos)')
            ylabel(app.UIAxes, 'Altura(metros)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Tiempo')
            ylabel(app.UIAxes2, 'Velocidad')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'Tiempo')
            ylabel(app.UIAxes3, 'Fuerza')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.AlturaLabel = uilabel(app.UIFigure);
            app.AlturaLabel.Text = 'Altura';
            app.Altura = uieditfield(app.UIFigure, 'numeric');
            app.Altura.Value = 30;
            app.MasaLabel = uilabel(app.UIFigure);
            app.MasaLabel.Text = 'Masa';
            app.Masa = uieditfield(app.UIFigure, 'numeric');
            app.Masa.Value = 12;
            app.ResistenciaLabel = uilabel(app.UIFigure);
            app.ResistenciaLabel.Text = 'Resistencia';
            app.Resistencia = uieditfield(app.UIFigure, 'numeric');
            app.Resistencia.Value = 1.3e-08;
            app.RadioLabel = uilabel(app.UIFigure);
            app.RadioLabel.Text = 'Radio';
            app.Radio = uieditfield(app.UIFigure, 'numeric');
            app.Radio.Value = 0.12;
            app.BorrarButton = uibutton(app.UIFigure, 'push');
            app.BorrarButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.BorrarButton.Text = 'Borrar ';
            app.AlturaLabel_2 = uilabel(app.UIFigure);
            app.AlturaLabel_2.Text = 'Tiempo de ejecuccion';
            app.Tiempo = uieditfield(app.UIFigure, 'numeric');
            app.Tiempo.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end